classdef CR250interactive < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CR250interactiveUIFigure        matlab.ui.Figure
        MaintenanceMenu                 matlab.ui.container.Menu
        compileMexDriverMenuItem        matlab.ui.container.Menu
        DeviceConfigMenu                matlab.ui.container.Menu
        openCR250MenuItem               matlab.ui.container.Menu
        syncMenuItem                    matlab.ui.container.Menu
        noneSyncMenuItem                matlab.ui.container.Menu
        manualSyncMenuItem              matlab.ui.container.Menu
        ntscSyncMenuItem                matlab.ui.container.Menu
        palSyncMenuItem                 matlab.ui.container.Menu
        cinemaSyncMenuItem              matlab.ui.container.Menu
        toggleEchoMenuItem              matlab.ui.container.Menu
        closeCR250MenuItem              matlab.ui.container.Menu
        verbosity                       matlab.ui.container.Menu
        minimumMenu                     matlab.ui.container.Menu
        defaultMenu                     matlab.ui.container.Menu
        maxMenu                         matlab.ui.container.Menu
        OperationsMenu                  matlab.ui.container.Menu
        takeMeasurementMenuItem         matlab.ui.container.Menu
        retrieveLastMesurementMenuItem  matlab.ui.container.Menu
        saveLastMeasurementMenuItem     matlab.ui.container.Menu
        calibrationTargets              matlab.ui.container.Menu
        calibrationTargetsON            matlab.ui.container.Menu
        calibrationTargetsOFF           matlab.ui.container.Menu
        DeviceInfoMenu                  matlab.ui.container.Menu
        deviceInfoMenuItem              matlab.ui.container.Menu
        spectrumAxes                    matlab.ui.control.UIAxes
        redCalibrationAxes              matlab.ui.control.UIAxes
        greenCalibrationAxes            matlab.ui.control.UIAxes
        blueCalibrationAxes             matlab.ui.control.UIAxes
    end

    % CR250interactiveApp- Demonstrates the usage of the CR250_device driver for
    % controlling the CR250 colorimeter.
    %
    %  History:
    %    April 2025  NPC  Wrote it
    %

    properties (Access = private)
        % The open CR250dev
        theCR250dev = [];

        % The last measurement data
        theSpectralSupport = [];
        theSpectrum = [];
    end
    
    methods (Access = private)
        
        function plotLastMeasurement(app)
            bar(app.spectrumAxes, app.theSpectralSupport, app.theSpectrum, 1.0, ...
                'FaceColor', [1 0.5 0.5], ...
                'EdgeColor', [1 0 0], 'LineWidth', 0.5);
            set(app.spectrumAxes, 'XLim', [370 780], 'XTick', 300:25:800);
            set(app.spectrumAxes, 'FontSize', 16);
            grid(app.spectrumAxes, 'on');
        end

        function initializeSpectrumPlot(app)
            set(app.spectrumAxes, 'XLim', [370 800], 'XTick', 300:25:800);
            set(app.spectrumAxes, 'FontSize', 16)
        end

        function disableMenuItemsThatNeedAnOpenCR250(app)
            % Disable all menu items until we have opened the CR250
            set(app.takeMeasurementMenuItem, 'Enable', 'off');
            set(app.retrieveLastMesurementMenuItem, 'Enable', 'off');
            set(app.toggleEchoMenuItem, 'Enable', 'off');
            set(app.syncMenuItem, 'Enable', 'off');
            set(app.deviceInfoMenuItem, 'Enable', 'off');
            set(app.closeCR250MenuItem, 'Enable', 'off');
        end

        function enableAllMenuItems(app)
            % Enable all menu items 
            set(app.takeMeasurementMenuItem, 'Enable', 'on');
            set(app.retrieveLastMesurementMenuItem, 'Enable', 'on');
            set(app.toggleEchoMenuItem, 'Enable', 'on');
            set(app.syncMenuItem, 'Enable', 'on');
            set(app.deviceInfoMenuItem, 'Enable', 'on');
            set(app.closeCR250MenuItem, 'Enable', 'on');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.CR250interactiveUIFigure.Name = 'CR250-interactive';
            app.initializeSpectrumPlot();
            
            app.theSpectralSupport = 370:2:800;
            app.theSpectrum = rand([1 numel(app.theSpectralSupport)]);
            app.plotLastMeasurement();
            app.disableMenuItemsThatNeedAnOpenCR250();
        end

        % Menu selected function: takeMeasurementMenuItem
        function takeMeasurement(app, event)
            app.theCR250dev.measure();
            [app.theSpectralSupport, app.theSpectrum] = app.theCR250dev.retrieveMeasurement();
            app.plotLastMeasurement();
        end

        % Menu selected function: compileMexDriverMenuItem
        function compileMexDriver(app, event)
            CR250device.compileMexDriver();
            app.disableMenuItemsThatNeedAnOpenCR250();
        end

        % Menu selected function: openCR250MenuItem
        function openCR250(app, event)
            app.theCR250dev = CR250device();
            app.theCR250dev.verbosity = 'min';
            app.enableAllMenuItems();
        end

        % Menu selected function: manualSyncMenuItem
        function setManualSyncMode(app, event)
            app.theCR250dev.syncMode = 'Manual';
        end

        % Menu selected function: noneSyncMenuItem
        function setNoneSyncMode(app, event)
            app.theCR250dev.syncMode = 'None';
        end

        % Menu selected function: ntscSyncMenuItem
        function setNTSCSyncMode(app, event)
            app.theCR250dev.syncMode = 'NTSC';
        end

        % Menu selected function: palSyncMenuItem
        function setPALSyncMode(app, event)
            app.theCR250dev.syncMode = 'PAL';
        end

        % Menu selected function: cinemaSyncMenuItem
        function setCINEMASyncMode(app, event)
            app.theCR250dev.syncMode = 'CINEMA';
        end

        % Menu selected function: deviceInfoMenuItem
        function CR250info(app, event)
            app.theCR250dev.deviceConfig();
        end

        % Menu selected function: toggleEchoMenuItem
        function toggleEcho(app, event)
            app.theCR250dev.toggleEcho()
        end

        % Menu selected function: retrieveLastMesurementMenuItem
        function retieveLastMeasuement(app, event)
            [app.theSpectralSupport, app.theSpectrum] = ...
                app.theCR250dev.retrieveMeasurement();
             app.plotLastMeasurement();
        end

        % Menu selected function: closeCR250MenuItem
        function closeCR250(app, event)
            app.theCR250dev.close();
            app.disableMenuItemsThatNeedAnOpenCR250();
        end

        % Menu selected function: minimumMenu
        function minVerbosity(app, event)
            app.theCR250dev.verbosity = 'min';
        end

        % Menu selected function: defaultMenu
        function defaultVerbosity(app, event)
            app.theCR250dev.verbosity = 'default';
        end

        % Menu selected function: maxMenu
        function maximumVerbosity(app, event)
            app.theCR250dev.verbosity = 'max';
        end

        % Menu selected function: calibrationTargetsON
        function RGCcalibrationTargetsON(app, event)
            set(app.redCalibrationAxes, 'Visible', 'on')
            set(app.greenCalibrationAxes, 'Visible', 'on')
            set(app.blueCalibrationAxes, 'Visible', 'on')
        end

        % Menu selected function: calibrationTargetsOFF
        function RGCcalibrationTargetsOFF(app, event)
            set(app.redCalibrationAxes, 'Visible', 'off')
            set(app.greenCalibrationAxes, 'Visible', 'off')
            set(app.blueCalibrationAxes, 'Visible', 'off')
        end

        % Menu selected function: saveLastMeasurementMenuItem
        function saveLastMeasurement(app, event)
            theSPD = struct(...
                'support', app.theSpectralSupport, ...
                'radiance', app.theSpectrum);
            theMeasurementTimeStamp = datetime('now','TimeZone','local','Format','yyyy-MM-dd@HH-mm');
            theMatFilename = sprintf('CR250meas%s.mat', theMeasurementTimeStamp);
            uisave({'theSPD','theMeasurementTimeStamp'},theMatFilename);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CR250interactiveUIFigure = uifigure('Visible', 'off');
            app.CR250interactiveUIFigure.Name = 'CR250-interactive';
            app.MaintenanceMenu = uimenu(app.CR250interactiveUIFigure);
            app.MaintenanceMenu.Text = 'Maintenance       ';
            app.compileMexDriverMenuItem = uimenu(app.MaintenanceMenu);
            app.compileMexDriverMenuItem.MenuSelectedFcn = createCallbackFcn(app, @compileMexDriver, true);
            app.compileMexDriverMenuItem.Text = 'compile MEX driver';
            app.DeviceConfigMenu = uimenu(app.CR250interactiveUIFigure);
            app.DeviceConfigMenu.Text = 'Device Config       ';
            app.openCR250MenuItem = uimenu(app.DeviceConfigMenu);
            app.openCR250MenuItem.MenuSelectedFcn = createCallbackFcn(app, @openCR250, true);
            app.openCR250MenuItem.Text = 'open CR250';
            app.syncMenuItem = uimenu(app.DeviceConfigMenu);
            app.syncMenuItem.Text = 'sync mode';
            app.noneSyncMenuItem = uimenu(app.syncMenuItem);
            app.noneSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setNoneSyncMode, true);
            app.noneSyncMenuItem.Text = 'none';
            app.manualSyncMenuItem = uimenu(app.syncMenuItem);
            app.manualSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setManualSyncMode, true);
            app.manualSyncMenuItem.Text = 'manual';
            app.ntscSyncMenuItem = uimenu(app.syncMenuItem);
            app.ntscSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setNTSCSyncMode, true);
            app.ntscSyncMenuItem.Text = 'NTSC';
            app.palSyncMenuItem = uimenu(app.syncMenuItem);
            app.palSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setPALSyncMode, true);
            app.palSyncMenuItem.Text = 'PAL';
            app.cinemaSyncMenuItem = uimenu(app.syncMenuItem);
            app.cinemaSyncMenuItem.MenuSelectedFcn = createCallbackFcn(app, @setCINEMASyncMode, true);
            app.cinemaSyncMenuItem.Text = 'CINEMA';
            app.toggleEchoMenuItem = uimenu(app.DeviceConfigMenu);
            app.toggleEchoMenuItem.MenuSelectedFcn = createCallbackFcn(app, @toggleEcho, true);
            app.toggleEchoMenuItem.Text = 'toggleEcho';
            app.closeCR250MenuItem = uimenu(app.DeviceConfigMenu);
            app.closeCR250MenuItem.MenuSelectedFcn = createCallbackFcn(app, @closeCR250, true);
            app.closeCR250MenuItem.Text = 'close CR250';
            app.verbosity = uimenu(app.DeviceConfigMenu);
            app.verbosity.Text = 'verbosity';
            app.minimumMenu = uimenu(app.verbosity);
            app.minimumMenu.MenuSelectedFcn = createCallbackFcn(app, @minVerbosity, true);
            app.minimumMenu.Text = 'minimum';
            app.defaultMenu = uimenu(app.verbosity);
            app.defaultMenu.MenuSelectedFcn = createCallbackFcn(app, @defaultVerbosity, true);
            app.defaultMenu.Text = 'default';
            app.maxMenu = uimenu(app.verbosity);
            app.maxMenu.MenuSelectedFcn = createCallbackFcn(app, @maximumVerbosity, true);
            app.maxMenu.Text = 'max';
            app.OperationsMenu = uimenu(app.CR250interactiveUIFigure);
            app.OperationsMenu.Text = 'Operations      ';
            app.takeMeasurementMenuItem = uimenu(app.OperationsMenu);
            app.takeMeasurementMenuItem.MenuSelectedFcn = createCallbackFcn(app, @takeMeasurement, true);
            app.takeMeasurementMenuItem.Text = 'measure spectrum';
            app.retrieveLastMesurementMenuItem = uimenu(app.OperationsMenu);
            app.retrieveLastMesurementMenuItem.MenuSelectedFcn = createCallbackFcn(app, @retieveLastMeasuement, true);
            app.retrieveLastMesurementMenuItem.Text = 'retrieve last mesurement';
            app.saveLastMeasurementMenuItem = uimenu(app.OperationsMenu);
            app.saveLastMeasurementMenuItem.MenuSelectedFcn = createCallbackFcn(app, @saveLastMeasurement, true);
            app.saveLastMeasurementMenuItem.Text = 'save last measurement';
            app.calibrationTargets = uimenu(app.OperationsMenu);
            app.calibrationTargets.Text = 'RGB calibration targets';
            app.calibrationTargetsON = uimenu(app.calibrationTargets);
            app.calibrationTargetsON.MenuSelectedFcn = createCallbackFcn(app, @RGCcalibrationTargetsON, true);
            app.calibrationTargetsON.Text = 'ON';
            app.calibrationTargetsOFF = uimenu(app.calibrationTargets);
            app.calibrationTargetsOFF.MenuSelectedFcn = createCallbackFcn(app, @RGCcalibrationTargetsOFF, true);
            app.calibrationTargetsOFF.Text = 'OFF';
            app.DeviceInfoMenu = uimenu(app.CR250interactiveUIFigure);
            app.DeviceInfoMenu.Text = 'Device Info';
            app.deviceInfoMenuItem = uimenu(app.DeviceInfoMenu);
            app.deviceInfoMenuItem.MenuSelectedFcn = createCallbackFcn(app, @CR250info, true);
            app.deviceInfoMenuItem.Text = 'CR250info';
            app.blueCalibrationAxes = uiaxes(app.CR250interactiveUIFigure);
            app.blueCalibrationAxes.XTick = [];
            app.blueCalibrationAxes.YTick = [];
            app.greenCalibrationAxes = uiaxes(app.CR250interactiveUIFigure);
            app.greenCalibrationAxes.XTick = [];
            app.greenCalibrationAxes.YTick = [];
            app.redCalibrationAxes = uiaxes(app.CR250interactiveUIFigure);
            app.redCalibrationAxes.XTick = [];
            app.redCalibrationAxes.YTick = [];
            app.spectrumAxes = uiaxes(app.CR250interactiveUIFigure);
            xlabel(app.spectrumAxes, 'wavelength (nm)')
            ylabel(app.spectrumAxes, 'radiance')
            zlabel(app.spectrumAxes, 'Z')
            app.spectrumAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            % Show the figure after all components are created
            app.CR250interactiveUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CR250interactive

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CR250interactiveUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CR250interactiveUIFigure)
        end
    end
end